classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AnewversionthatwillcontainsAIalgorithmsiscomingverysoonLabel  matlab.ui.control.Label
        segmentation2Button         matlab.ui.control.Button
        segmentationwithmaskButton  matlab.ui.control.Button
        binaryimageButton           matlab.ui.control.Button
        filteringButton             matlab.ui.control.Button
        AdjustingcontrastButton     matlab.ui.control.Button
        UploadButton                matlab.ui.control.Button
        MenuTextArea                matlab.ui.control.TextArea
        MenuTextAreaLabel           matlab.ui.control.Label
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imshow(a,'Parent',app.UIAxes);
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button pushed function: AdjustingcontrastButton
        function AdjustingcontrastButtonPushed(app, event)
            global adjustedImage;
            global a;
            adjustedImage = imadjust(a, [], [], 2);
            imshow(adjustedImage ,'Parent',app.UIAxes2);
           
        end

        % Button pushed function: filteringButton
        function filteringButtonPushed(app, event)
            global filtered; 
            global adjustedImage;
            
            filtersize = [20 20];
            adjustedImage = rgb2gray(adjustedImage);
            filtered = medfilt2(adjustedImage,filtersize);
            
            imshow(filtered ,'Parent',app.UIAxes5);


        end

        % Button pushed function: binaryimageButton
        function binaryimageButtonPushed(app, event)
            global filtered; 
            
            global reefBW;
            filtredBW = filtered>100;
            reefBW = bwareaopen(filtredBW,1200);    
            imshow(reefBW,'Parent',app.UIAxes3);
            
        end

        % Button pushed function: segmentationwithmaskButton
        function segmentationwithmaskButtonPushed(app, event)
            
            global a;
            global reefBW;
            global final;
            final = imoverlay(a,reefBW);
            imshow(final,'Parent',app.UIAxes4);
             
        end

        % Button pushed function: segmentation2Button
        function segmentation2ButtonPushed(app, event)
            global filtred; 
            global adjustedImage;
            global a;
            global reefBW;
            global final;
            global B;
            B = bwboundaries(reefBW, "noholes");
            imshow(a, 'Parent', app.UIAxes6);
            hold(app.UIAxes6, 'on');

             % Loop over each boundary and plot it on UIAxes6
             for k = 1:numel(B)
                boundary = B{k};
               plot(app.UIAxes6, boundary(:, 2), boundary(:, 1), 'r', 'LineWidth', 2);
             end
             hold(app.UIAxes6, 'off');

 
        end

        % Callback function
        function EffacerButtonPushed(app, event)
                 cla(app.UIAxes);
  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original image')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'adjusted contrast image')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Mask of the image')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'segmentation using mask')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'filtred image')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'segmentation 2')
            app.MenuTextAreaLabel = uilabel(app.UIFigure);
            app.MenuTextAreaLabel.Text = 'Menu :';
            app.MenuTextArea = uitextarea(app.UIFigure);
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.AdjustingcontrastButton = uibutton(app.UIFigure, 'push');
            app.AdjustingcontrastButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustingcontrastButtonPushed, true);
            app.AdjustingcontrastButton.Text = 'Adjusting contrast';
            app.filteringButton = uibutton(app.UIFigure, 'push');
            app.filteringButton.ButtonPushedFcn = createCallbackFcn(app, @filteringButtonPushed, true);
            app.filteringButton.Text = 'filtering';
            app.binaryimageButton = uibutton(app.UIFigure, 'push');
            app.binaryimageButton.ButtonPushedFcn = createCallbackFcn(app, @binaryimageButtonPushed, true);
            app.binaryimageButton.Text = 'binary image';
            app.segmentationwithmaskButton = uibutton(app.UIFigure, 'push');
            app.segmentationwithmaskButton.ButtonPushedFcn = createCallbackFcn(app, @segmentationwithmaskButtonPushed, true);
            app.segmentationwithmaskButton.Text = 'segmentation with mask';
            app.segmentation2Button = uibutton(app.UIFigure, 'push');
            app.segmentation2Button.ButtonPushedFcn = createCallbackFcn(app, @segmentation2ButtonPushed, true);
            app.segmentation2Button.Text = 'segmentation 2';
            app.AnewversionthatwillcontainsAIalgorithmsiscomingverysoonLabel = uilabel(app.UIFigure);
            app.AnewversionthatwillcontainsAIalgorithmsiscomingverysoonLabel.Text = 'Status  : A new version that will contains AI algorithms is coming very soon !!!!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end